classdef Image_Editor < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                     matlab.ui.Figure
        AppName                      matlab.ui.control.Label
        AppIcon                      matlab.ui.control.Image
        ByLabel                      matlab.ui.control.Label
        OpenButton                   matlab.ui.control.Button
        ColorSegmentationPanel       matlab.ui.container.Panel
        ColorHighlightPanel          matlab.ui.container.Panel
        SelectButton_2               matlab.ui.control.Button
        LowerLabel_2                 matlab.ui.control.Label
        UpperLabel_2                 matlab.ui.control.Label
        LowerValLevelSlider_2        matlab.ui.control.Slider
        RotateLabel_15               matlab.ui.control.Label
        LowerSatLevelSlider_2        matlab.ui.control.Slider
        RotateLabel_14               matlab.ui.control.Label
        LowerHueLevelSlider_2        matlab.ui.control.Slider
        RotateLabel_13               matlab.ui.control.Label
        UpperValLevelSlider_2        matlab.ui.control.Slider
        RotateLabel_12               matlab.ui.control.Label
        UpperSatLevelSlider_2        matlab.ui.control.Slider
        RotateLabel_11               matlab.ui.control.Label
        UpperHueLevelSlider_2        matlab.ui.control.Slider
        RotateLabel_10               matlab.ui.control.Label
        ColorHighlightCrossButton    matlab.ui.control.Button
        ColorHighlightTickButton     matlab.ui.control.Button
        ColorAbstractionPanel        matlab.ui.container.Panel
        SelectButton                 matlab.ui.control.Button
        LowerLabel                   matlab.ui.control.Label
        UpperLabel                   matlab.ui.control.Label
        LowerValLevelSlider          matlab.ui.control.Slider
        RotateLabel_9                matlab.ui.control.Label
        LowerSatLevelSlider          matlab.ui.control.Slider
        RotateLabel_8                matlab.ui.control.Label
        LowerHueLevelSlider          matlab.ui.control.Slider
        RotateLabel_7                matlab.ui.control.Label
        UpperValLevelSlider          matlab.ui.control.Slider
        RotateLabel_6                matlab.ui.control.Label
        UpperSatLevelSlider          matlab.ui.control.Slider
        RotateLabel_5                matlab.ui.control.Label
        UpperHueLevelSlider          matlab.ui.control.Slider
        RotateLabel_4                matlab.ui.control.Label
        ColorAbstractionCrossButton  matlab.ui.control.Button
        ColorAbstractionTickButton   matlab.ui.control.Button
        ColorHighlightButton         matlab.ui.control.Button
        ColorHighlightLabel          matlab.ui.control.Label
        ColorAbstractionButton       matlab.ui.control.Button
        ColorAbstractionButtonLabel  matlab.ui.control.Label
        BackButton_3                 matlab.ui.control.Button
        FilterPanel                  matlab.ui.container.Panel
        RadialBlurPanel              matlab.ui.container.Panel
        RadialBlurLevelSlider        matlab.ui.control.Slider
        RotateLabel_3                matlab.ui.control.Label
        RadialBlurCrossButton        matlab.ui.control.Button
        RadialBlurTickButton         matlab.ui.control.Button
        CannyPanel                   matlab.ui.container.Panel
        CannyLevelSlider             matlab.ui.control.Slider
        RotateLabel_2                matlab.ui.control.Label
        CannyCrossButton             matlab.ui.control.Button
        CannyTickButton              matlab.ui.control.Button
        ReduceColorDistortionButton  matlab.ui.control.Button
        ImageEnhancementButton       matlab.ui.control.Button
        LinearBlurButton             matlab.ui.control.Button
        RadialBlurButton             matlab.ui.control.Button
        VignetteButton               matlab.ui.control.Button
        CannyEdgeDetectionButton     matlab.ui.control.Button
        YCbCrConversionButton        matlab.ui.control.Button
        HSVConversionButton          matlab.ui.control.Button
        GrayScaleButton              matlab.ui.control.Button
        FilterCrossButton            matlab.ui.control.Button
        FilterTickButton             matlab.ui.control.Button
        BackButton_2                 matlab.ui.control.Button
        AdjustPanel                  matlab.ui.container.Panel
        TintButtonLabel              matlab.ui.control.Label
        TintButton                   matlab.ui.control.Button
        CropButton                   matlab.ui.control.Button
        CropButtonLabel              matlab.ui.control.Label
        RotateButtonLabel            matlab.ui.control.Label
        RotateButton                 matlab.ui.control.Button
        SaturationButton             matlab.ui.control.Button
        SaturationButtonLabel        matlab.ui.control.Label
        ContrastButton               matlab.ui.control.Button
        ContrastButtonLabel          matlab.ui.control.Label
        ResizeButton                 matlab.ui.control.Button
        ResizeButtonLabel            matlab.ui.control.Label
        BrightnessButton             matlab.ui.control.Button
        BrightnessButtonLabel        matlab.ui.control.Label
        BackButton                   matlab.ui.control.Button
        CropPanel                    matlab.ui.container.Panel
        DrawtherectangleofyourdesiredAreaLabel  matlab.ui.control.Label
        CropCrossButton              matlab.ui.control.Button
        CropTickButton               matlab.ui.control.Button
        RotatePanel                  matlab.ui.container.Panel
        RotateLevelSlider            matlab.ui.control.Slider
        RotateLabel                  matlab.ui.control.Label
        RotateCrossButton            matlab.ui.control.Button
        RotateTickButton             matlab.ui.control.Button
        ContrastPanel                matlab.ui.container.Panel
        ContrastLevelSlider          matlab.ui.control.Slider
        ContrastLevelLabel           matlab.ui.control.Label
        ContrastCrossButton          matlab.ui.control.Button
        ContrastTickButton           matlab.ui.control.Button
        SaturationPanel              matlab.ui.container.Panel
        SaturationLevelSlider        matlab.ui.control.Slider
        SaturationLevelSliderLabel   matlab.ui.control.Label
        SaturationCrossButton        matlab.ui.control.Button
        SaturationTickButton         matlab.ui.control.Button
        BrightnessPanel              matlab.ui.container.Panel
        BrightnessLevelSlider        matlab.ui.control.Slider
        BrightnessLevelSliderLabel   matlab.ui.control.Label
        BrightnessCrossButton        matlab.ui.control.Button
        BrightnessTickButton         matlab.ui.control.Button
        TintPanel                    matlab.ui.container.Panel
        BlueLevelSlider              matlab.ui.control.Slider
        RotateLabel_18               matlab.ui.control.Label
        GreenLevelSlider             matlab.ui.control.Slider
        RotateLabel_17               matlab.ui.control.Label
        RedLevelSlider               matlab.ui.control.Slider
        RotateLabel_16               matlab.ui.control.Label
        TintCrossButton              matlab.ui.control.Button
        TintTickButton               matlab.ui.control.Button
        ResizePanel                  matlab.ui.container.Panel
        EnterButton                  matlab.ui.control.Button
        HeightEditField              matlab.ui.control.NumericEditField
        HeightEditFieldLabel         matlab.ui.control.Label
        WidthEditField               matlab.ui.control.NumericEditField
        WidthEditFieldLabel          matlab.ui.control.Label
        OrientationButtonGroup       matlab.ui.container.ButtonGroup
        LandscapeButton              matlab.ui.control.RadioButton
        PortraitButton               matlab.ui.control.RadioButton
        ResizeCrossButton            matlab.ui.control.Button
        ResizeTickButton             matlab.ui.control.Button
        ResizetheImageDropDown       matlab.ui.control.DropDown
        ResizetheImageDropDownLabel  matlab.ui.control.Label
        ColorSegmentationLabel       matlab.ui.control.Label
        ColorSegmentationButton      matlab.ui.control.Button
        FiltersLabel                 matlab.ui.control.Label
        FiltersButton                matlab.ui.control.Button
        AdjustLabel                  matlab.ui.control.Label
        AdjustButton                 matlab.ui.control.Button
        ExitButton                   matlab.ui.control.Button
        ResetButton                  matlab.ui.control.Button
        SaveButton                   matlab.ui.control.Button
        OpenButton_2                 matlab.ui.control.Button
        Panel                        matlab.ui.container.Panel
        UIAxes                       matlab.ui.control.UIAxes
        HTML                         matlab.ui.control.HTML
    end

    
    methods (Access = private)
        
        function startupPage(app)
            % Only Open button to be visible

            % On Widgets
            app.OpenButton.Visible = 'on';
            app.AppName.Visible = 'on';
            app.AppIcon.Visible = 'on';
            app.ByLabel.Visible = 'on';
            

            % Off Widgets
            app.ExitButton.Visible = 'off';
            app.ResetButton.Visible = 'off';
            app.SaveButton.Visible = 'off';
            app.OpenButton_2.Visible = 'off';      
            app.Panel.Visible = 'off';
            app.AdjustButton.Visible = 'off';
            app.AdjustLabel.Visible = 'off';
            app.FiltersButton.Visible = 'off';
            app.FiltersLabel.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            app.ColorSegmentationLabel.Visible = 'off';
        end
        
        function mainMenu(app)
            % Widgets to be visible in Main Menu
            
            % Off Widgets
            app.OpenButton.Visible = 'off';
            app.AppName.Visible = 'off';
            app.AppIcon.Visible = 'off';
            app.ByLabel.Visible = 'off';
            app.AdjustPanel.Visible = 'off';
            app.FilterPanel.Visible = 'off';
            app.ColorSegmentationPanel.Visible = 'off';

            % On Widgets
            app.ExitButton.Visible = 'on';
            app.ResetButton.Visible = 'on';
            app.SaveButton.Visible = 'on';
            app.OpenButton_2.Visible = 'on';
            app.Panel.Visible = 'on';
            app.AdjustButton.Visible = 'on';
            app.AdjustLabel.Visible = 'on';
            app.FiltersButton.Visible = 'on';
            app.FiltersLabel.Visible = 'on';
            app.ColorSegmentationButton.Visible = 'on';
            app.ColorSegmentationLabel.Visible = 'on';
        end
        
        function adjustMenu(app)
            app.AdjustButton.Visible = 'off';
            app.AdjustLabel.Visible = 'off';
            app.FiltersButton.Visible = 'off';
            app.FiltersLabel.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            
            app.AdjustPanel.Visible = 'on';

            app.ResizePanel.Visible = 'off';
            app.BrightnessPanel.Visible = 'off';
            app.SaturationPanel.Visible = 'off';
            app.ContrastPanel.Visible = 'off';
            app.RotatePanel.Visible = 'off';
            app.CropPanel.Visible = 'off';
            app.TintPanel.Visible = 'off';
        end
        
        function filterMenu(app)
            app.AdjustButton.Visible = 'off';
            app.AdjustLabel.Visible = 'off';
            app.FiltersButton.Visible = 'off';
            app.FiltersLabel.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            
            app.FilterPanel.Visible = 'on';

            app.CannyPanel.Visible = 'off';
            app.RadialBlurPanel.Visible = 'off';
        end
        
        function colorSegmentationMenu(app)
            app.AdjustButton.Visible = 'off';
            app.AdjustLabel.Visible = 'off';
            app.FiltersButton.Visible = 'off';
            app.FiltersLabel.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            app.ColorSegmentationButton.Visible = 'off';
            
            app.ColorSegmentationPanel.Visible = 'on';

            app.ColorAbstractionPanel.Visible = 'off';
            app.ColorHighlightPanel.Visible = 'off';
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: OpenButton, OpenButton_2
        function OpenButtonPushed(app, event)
            global img;
            global newimg;
            
            % Reading Files
            [filename, pathname] = uigetfile({'*.jpeg;*.jpg','JPEG/JPG Files (*.jpeg, *.jpg)'; ...
                                              '*.png', 'PNG Files (*.png)'; ...
                                              '*.*','All Files (*.*)'}, 'Select an Image');
            filename = strcat(pathname, filename);
            img = imread(filename);

            newimg = img;

            % Aesthetics
            mainMenu(app);
            
            % Plotting the Image
            imshow(newimg,'Parent', app.UIAxes);
        end

        % Button pushed function: ExitButton
        function ExitButtonPushed(app, event)
            startupPage(app);
        end

        % Button pushed function: AdjustButton
        function AdjustButtonPushed(app, event)
            adjustMenu(app);
        end

        % Button pushed function: BackButton, BackButton_2, BackButton_3
        function BackButtonPushed(app, event)
            global newimg;

            imshow(newimg, 'Parent', app.UIAxes);
            mainMenu(app);
        end

        % Button pushed function: ResizeTickButton
        function ResizeTickButtonPushed(app, event)
            global resizedimg;
            global newimg;

            newimg = resizedimg;
            imshow(newimg, 'Parent', app.UIAxes);

            adjustMenu(app);
        end

        % Button pushed function: BrightnessCrossButton, 
        % ...and 6 other components
        function ResizeCrossButtonPushed(app, event)
            global newimg;

            adjustMenu(app);

            imshow(newimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: ResizeButton
        function ResizeButtonPushed(app, event)
            app.WidthEditField.Visible = 'off';
            app.HeightEditField.Visible = 'off';
            app.HeightEditFieldLabel.Visible = 'off';
            app.WidthEditFieldLabel.Visible = 'off';
            app.EnterButton.Visible = 'off';
            
            app.ResizePanel.Visible = 'on';
        end

        % Value changed function: ResizetheImageDropDown
        function ResizetheImageDropDownValueChanged(app, event)
            global newimg;
            global resizedimg;

            resizeDDval = app.ResizetheImageDropDown.Value;
            switch resizeDDval
                case 'Current'
                    app.WidthEditField.Visible = 'off';
                    app.HeightEditField.Visible = 'off';
                    app.HeightEditFieldLabel.Visible = 'off';
                    app.WidthEditFieldLabel.Visible = 'off';
                    app.EnterButton.Visible = 'off';

                    resizedimg = newimg;

                case '1920 x 1080'
                    app.WidthEditField.Visible = 'off';
                    app.HeightEditField.Visible = 'off';
                    app.HeightEditFieldLabel.Visible = 'off';
                    app.WidthEditFieldLabel.Visible = 'off';
                    app.EnterButton.Visible = 'off';

                    if app.LandscapeButton.Value == 1
                        resizedimg = imresize(newimg, [1080 1920]);
                    else 
                        resizedimg = imresize(newimg, [1920 1080]);
                    end
                
                case '1280 x 720'
                    app.WidthEditField.Visible = 'off';
                    app.HeightEditField.Visible = 'off';
                    app.HeightEditFieldLabel.Visible = 'off';
                    app.WidthEditFieldLabel.Visible = 'off';
                    app.EnterButton.Visible = 'off';

                    if app.LandscapeButton.Value == 1
                        resizedimg = imresize(newimg, [720 1280]);
                    else 
                        resizedimg = imresize(newimg, [1280 720]);
                    end
                
                case '640 x 480'
                    app.WidthEditField.Visible = 'off';
                    app.HeightEditField.Visible = 'off';
                    app.HeightEditFieldLabel.Visible = 'off';
                    app.WidthEditFieldLabel.Visible = 'off';
                    app.EnterButton.Visible = 'off';

                    if app.LandscapeButton.Value == 1
                        resizedimg = imresize(newimg, [480 640]);
                    else 
                        resizedimg = imresize(newimg, [640 480]);
                    end
                
                case '320 x 240'
                    app.WidthEditField.Visible = 'off';
                    app.HeightEditField.Visible = 'off';
                    app.HeightEditFieldLabel.Visible = 'off';
                    app.WidthEditFieldLabel.Visible = 'off';
                    app.EnterButton.Visible = 'off';

                    if app.LandscapeButton.Value == 1
                        resizedimg = imresize(newimg, [240 320]);
                    else 
                        resizedimg = imresize(newimg, [320 240]);
                    end
                
                case 'Custom'
                    app.WidthEditField.Visible = 'on';
                    app.HeightEditField.Visible = 'on';
                    app.HeightEditFieldLabel.Visible = 'on';
                    app.WidthEditFieldLabel.Visible = 'on';
                    app.EnterButton.Visible = 'on';
                    imshow(newimg, 'Parent', app.UIAxes);
            end
            
            imshow(resizedimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: EnterButton
        function EnterButtonPushed(app, event)
            global resizedimg;
            global newimg;

            width = app.WidthEditField.Value;
            height = app.HeightEditField.Value;
            
            if width ~= 0 || height ~= 0 
                resizedimg = imresize(newimg, [height width]);
            end
           
            imshow(resizedimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: SaveButton
        function SaveButtonPushed(app, event)
            imsave(app.UIAxes);
        end

        % Button pushed function: ResetButton
        function ResetButtonPushed(app, event)
            global img;
            global newimg;

            newimg = img;
            imshow(newimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: BrightnessTickButton
        function BrightnessTickButtonPushed(app, event)
            global brightimg;
            global newimg;

            newimg = brightimg;
            imshow(newimg, 'Parent', app.UIAxes);

            adjustMenu(app);
        end

        % Value changed function: BrightnessLevelSlider
        function BrightnessLevelSliderValueChanged(app, event)
            global brightimg;
            global newimg;

            bright = app.BrightnessLevelSlider.Value;

            brightimg = rgb2hsv(newimg);
            brightimg(:, :, 3) = brightimg(:, :, 3) + (brightimg(:, :, 3) * 0.01 * bright);
            brightimg = hsv2rgb(brightimg);

            imshow(brightimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: BrightnessButton
        function BrightnessButtonPushed(app, event)
            app.BrightnessPanel.Visible = 'on';
        end

        % Value changed function: SaturationLevelSlider
        function SaturationLevelSliderValueChanged(app, event)
            global saturateimg;
            global newimg;

            saturate = app.SaturationLevelSlider.Value;

            saturateimg = rgb2hsv(newimg);
            saturateimg(:, :, 2) = saturateimg(:, :, 2) + (saturateimg(:, :, 2) * 0.01 * saturate);
            saturateimg = hsv2rgb(saturateimg);

            imshow(saturateimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: SaturationTickButton
        function SaturationTickButtonPushed(app, event)
            global saturateimg;
            global newimg;

            newimg = saturateimg;
            imshow(newimg, 'Parent', app.UIAxes);

            adjustMenu(app);
        end

        % Button pushed function: SaturationButton
        function SaturationButtonPushed(app, event)
            app.SaturationPanel.Visible = 'on';
        end

        % Value changed function: ContrastLevelSlider
        function ContrastLevelSliderValueChanged(app, event)
            global contrastimg;
            global newimg;

            contrast = app.ContrastLevelSlider.Value;
            contrastimg = rgb2hsv(newimg);
            hChannel = contrastimg(:, :, 1);
            sChannel = contrastimg(:, :, 2);
            vChannel = contrastimg(:, :, 3);

            meanV = mean2(vChannel);
            mapfactor = 1 + (0.01 * contrast); % Mapping -100 to 100 to 0 to 2
            newV = meanV + mapfactor * (vChannel - meanV); 
            
            contrastimg = cat(3, hChannel, sChannel, newV);
            contrastimg = hsv2rgb(contrastimg);

            imshow(contrastimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: ContrastTickButton
        function ContrastTickButtonPushed(app, event)
            global contrastimg;
            global newimg;

            newimg = contrastimg;
            imshow(newimg, 'Parent', app.UIAxes);

            adjustMenu(app);
        end

        % Button pushed function: ContrastButton
        function ContrastButtonPushed(app, event)
            app.ContrastPanel.Visible = 'on';
        end

        % Value changed function: RotateLevelSlider
        function RotateLevelSliderValueChanged(app, event)
            global rotateimg;
            global newimg;

            rotate = app.RotateLevelSlider.Value;

            rotateimg = imrotate(newimg, rotate);

            imshow(rotateimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: RotateTickButton
        function RotateTickButtonPushed(app, event)
            global rotateimg;
            global newimg;

            newimg = rotateimg;
            imshow(newimg, 'Parent', app.UIAxes);

            adjustMenu(app);
        end

        % Button pushed function: RotateButton
        function RotateButtonPushed(app, event)
            app.RotatePanel.Visible = 'on';
        end

        % Button pushed function: GrayScaleButton
        function GrayScaleButtonPushed(app, event)
            global newimg;
            global filterimg;

            filterimg = rgb2gray(newimg);
            imshow(filterimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: CannyTickButton, FilterTickButton, 
        % ...and 1 other component
        function FilterTickButtonPushed(app, event)
            global filterimg;
            global newimg;

            newimg = filterimg;
            imshow(newimg, 'Parent', app.UIAxes);

            filterMenu(app);
        end

        % Button pushed function: CannyCrossButton, FilterCrossButton, 
        % ...and 1 other component
        function FilterCrossButtonPushed(app, event)
            global newimg;

            filterMenu(app);

            imshow(newimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: HSVConversionButton
        function HSVConversionButtonPushed(app, event)
            global newimg;
            global filterimg;

            filterimg = rgb2hsv(newimg);
            imshow(filterimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: YCbCrConversionButton
        function YCbCrConversionButtonPushed(app, event)
            global newimg;
            global filterimg;

            filterimg = rgb2ycbcr(newimg);
            imshow(filterimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: CannyEdgeDetectionButton
        function CannyEdgeDetectionButtonPushed(app, event)
            app.CannyPanel.Visible = 'on';
        end

        % Button pushed function: VignetteButton
        function VignetteButtonPushed(app, event)
            global newimg;
            global filterimg;
            
            sizex = size(newimg,2);
            sizey = size(newimg,1);
            xcenter = size(newimg,2)/2;
            ycenter = size(newimg,1)/2;

            [X,Y] = meshgrid((1:sizex)-xcenter,(1:sizey)-ycenter); %Define the x- and y-coordinates of the surface.
            
            %Define the radius from the center at each (x,y) coordinate.
            R2 = X.^2 + Y.^2;
            R2 = rescale(R2);
            weights = (1-R2);
            
            doublenewimg = im2double(newimg);
            filterimg = doublenewimg.*weights;
            imshow(filterimg, 'Parent', app.UIAxes);            
        end

        % Button pushed function: RadialBlurButton
        function RadialBlurButtonPushed(app, event)
            app.RadialBlurPanel.Visible = 'on';
        end

        % Button pushed function: ImageEnhancementButton
        function ImageEnhancementButtonPushed(app, event)
            global newimg;
            global filterimg;

            AInv = imcomplement(newimg);
            BInv = imreducehaze(AInv, 'Method','approx','ContrastEnhancement','boost');
            BImp = imcomplement(BInv);
            filterimg = imguidedfilter(BImp);

            imshow(filterimg, 'Parent', app.UIAxes); 
        end

        % Button pushed function: ReduceColorDistortionButton
        function ReduceColorDistortionButtonPushed(app, event)
            global newimg;
            global filterimg;

            labimg = rgb2lab(newimg);
            LInv = imcomplement(labimg(:,:,1) ./ 100);
            LEnh = imcomplement(imreducehaze(LInv,'ContrastEnhancement','none'));
            
            LabEnh(:,:,1)   = LEnh .* 100;
            LabEnh(:,:,2:3) = labimg(:,:,2:3) * 2; % Increase saturation
            
            filterimg = lab2rgb(LabEnh);
            imshow(filterimg, 'Parent', app.UIAxes); 
        end

        % Button pushed function: FiltersButton
        function FiltersButtonPushed(app, event)
            filterMenu(app);
        end

        % Value changed function: CannyLevelSlider
        function CannyLevelSliderValueChanged(app, event)
            global newimg;
            global filterimg;
            
            grayimg = rgb2gray(newimg);
            threshold = app.CannyLevelSlider.Value/100;
            
            if threshold ~= 0
                filterimg = edge(grayimg, 'canny', threshold);
            else
                filterimg = newimg;
            end
            imshow(filterimg, 'Parent', app.UIAxes); 
        end

        % Value changed function: RadialBlurLevelSlider
        function RadialBlurLevelSliderValueChanged(app, event)
            global newimg;
            global filterimg;
            
            doublenewimg = im2double(newimg);
            blurredimg = imgaussfilt(doublenewimg, 2);
            Strength = app.RadialBlurLevelSlider.Value;

            if Strength ~= 0
                weights = fspecial('gaussian', [size(newimg, 1) size(newimg, 2)], Strength);
                weights = rescale(weights);
                filterimg = doublenewimg.*weights + blurredimg.*(1-weights);  
            else
                filterimg = newimg;
            end
           
            imshow(filterimg, 'Parent', app.UIAxes);             
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            app.CropPanel.Visible = 'on';
            
            global newimg;
            global cropimg;
            
            imshow(newimg);
            h = imrect; %draw something 
            [row, col, channel] = size(newimg);
            M = ~h.createMask();
            
            halfcroppedimg = newimg;
            for k=1:channel
                for i=1:row
                    for j=1:col
                        if M(i,j) == 1
                            halfcroppedimg(i,j,k) = 0;
                        end
                    end
                end
            end
            
            % boundary check
            bottomleft = [0, 0];
            for i=1:row
                for j=1:col
                    if M(i,j) == 0
                        bottomleft = [i, j];
                        break
                    end
                end
            end
            
            topright = [0, 0];
            for j=1:col
                for i=1:row
                    if M(i,j) == 0
                        topright = [i, j];
                        break
                    end
                end
            end
            
            topleft = [topright(1, 1), bottomleft(1, 2)];
            bottomright = [bottomleft(1, 1), topright(1, 2)];
            
            width = topright(1, 2) - topleft(1, 2);
            height = bottomleft(1,1) - topleft(1, 1);
            
            cropimg = halfcroppedimg(topleft(1,1):bottomleft(1,1), topleft(1,2):topright(1,2),:);
            close all;

            imshow(cropimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: CropTickButton
        function CropTickButtonPushed(app, event)
            global cropimg;
            global newimg;

            newimg = cropimg;
            imshow(newimg, 'Parent', app.UIAxes);

            adjustMenu(app);
        end

        % Button pushed function: ColorAbstractionTickButton
        function ColorAbstractionTickButtonPushed(app, event)
            global abstractedimg;
            global newimg;

            newimg = abstractedimg;
            imshow(newimg, 'Parent', app.UIAxes);

            colorSegmentationMenu(app);
        end

        % Button pushed function: ColorAbstractionCrossButton, 
        % ...and 1 other component
        function ColorAbstractionCrossButtonPushed(app, event)
            global newimg;

            colorSegmentationMenu(app);

            imshow(newimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: ColorSegmentationButton
        function ColorSegmentationButtonPushed(app, event)
            colorSegmentationMenu(app);
        end

        % Button pushed function: ColorAbstractionButton
        function ColorAbstractionButtonPushed(app, event)
            app.ColorAbstractionPanel.Visible = 'on';
        end

        % Button pushed function: ColorHighlightTickButton
        function ColorHighlightTickButtonPushed(app, event)
            global highlightedimg;
            global newimg;

            newimg = highlightedimg;
            imshow(newimg, 'Parent', app.UIAxes);

            colorSegmentationMenu(app);
        end

        % Button pushed function: ColorHighlightButton
        function ColorHighlightButtonPushed(app, event)
            app.ColorHighlightPanel.Visible = 'on';
        end

        % Button pushed function: SelectButton
        function SelectButtonPushed(app, event)
            global newimg;
            global abstractedimg;
            
            imghsv = rgb2hsv(newimg);

            lowerbound = [app.LowerHueLevelSlider.Value/180, app.LowerSatLevelSlider.Value/255, app.LowerValLevelSlider.Value/255];
            upperbound = [app.UpperHueLevelSlider.Value/180, app.UpperSatLevelSlider.Value/255, app.UpperValLevelSlider.Value/255];
            
            [row, col, channel] = size(imghsv);
            
            % check hue
            for i = 1:row
                for j = 1:col
                    if (upperbound(1, 1) >= imghsv(i, j, 1)) && (lowerbound(1, 1) <= imghsv(i, j, 1))
                        huecheck(i, j) = 1;
                    else
                        huecheck(i, j) = 0;
                    end
                end
            end
            
            % check saturation
            for i = 1:row
                for j = 1:col
                    if (upperbound(1, 2) >= imghsv(i, j, 2)) && (lowerbound(1, 2) <= imghsv(i, j, 2))
                        saturationcheck(i, j) = 1;
                    else
                        saturationcheck(i, j) = 0;
                    end
                end
            end
            
            % check value
            for i = 1:row
                for j = 1:col
                    if (upperbound(1, 3) >= imghsv(i, j, 3)) && (lowerbound(1, 3) <= imghsv(i, j, 3))
                        valuecheck(i, j) = 1;
                    else
                        valuecheck(i, j) = 0;
                    end
                end
            end
            
            finalcheck = huecheck & saturationcheck & valuecheck;
            
            r = newimg(:,:,1);
            g = newimg(:,:,2);
            b = newimg(:,:,3);
            r(~finalcheck) = 255;
            g(~finalcheck) = 255;
            b(~finalcheck) = 255;
            
            % Reconstruct the RGB image:
            abstractedimg = cat(3,r,g,b);
            
            for k = 1:channel
                for i = 1:row
                    for j = 1:col
                        if abstractedimg(i, j, k) == 255
                            abstractedimg(i, j, k) = 0;
                        end
                    end
                end
            end
            imshow(abstractedimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: SelectButton_2
        function SelectButton_2Pushed(app, event)
            global newimg;
            global highlightedimg;
            
            imghsv = rgb2hsv(newimg);

            lowerbound = [app.LowerHueLevelSlider.Value/180, app.LowerSatLevelSlider.Value/255, app.LowerValLevelSlider.Value/255];
            upperbound = [app.UpperHueLevelSlider.Value/180, app.UpperSatLevelSlider.Value/255, app.UpperValLevelSlider.Value/255];
            
            [row, col, channel] = size(imghsv);
            
            % check hue
            for i = 1:row
                for j = 1:col
                    if (upperbound(1, 1) >= imghsv(i, j, 1)) && (lowerbound(1, 1) <= imghsv(i, j, 1))
                        huecheck(i, j) = 1;
                    else
                        huecheck(i, j) = 0;
                    end
                end
            end
            
            % check saturation
            for i = 1:row
                for j = 1:col
                    if (upperbound(1, 2) >= imghsv(i, j, 2)) && (lowerbound(1, 2) <= imghsv(i, j, 2))
                        saturationcheck(i, j) = 1;
                    else
                        saturationcheck(i, j) = 0;
                    end
                end
            end
            
            % check value
            for i = 1:row
                for j = 1:col
                    if (upperbound(1, 3) >= imghsv(i, j, 3)) && (lowerbound(1, 3) <= imghsv(i, j, 3))
                        valuecheck(i, j) = 1;
                    else
                        valuecheck(i, j) = 0;
                    end
                end
            end
            
            finalcheck = huecheck & saturationcheck & valuecheck;
         
            grayimg = rgb2gray(newimg);
            grayimg = im2double(grayimg);
            
            abstractedgray = grayimg - finalcheck;

            abstractedgrayfinal = zeros(size(newimg));
            abstractedgrayfinal(:, :, 1) = abstractedgray;
            abstractedgrayfinal(:, :, 2) = abstractedgray;
            abstractedgrayfinal(:, :, 3) = abstractedgray;

            r = newimg(:,:,1);
            g = newimg(:,:,2);
            b = newimg(:,:,3);
            r(~finalcheck) = 255;
            g(~finalcheck) = 255;
            b(~finalcheck) = 255;
            
            % Reconstruct the RGB image:
            abstractedimg = cat(3,r,g,b);
            
            for k = 1:channel
                for i = 1:row
                    for j = 1:col
                        if abstractedimg(i, j, k) == 255
                            abstractedimg(i, j, k) = 0;
                        end
                    end
                end
            end
            abstractedimg = im2double(abstractedimg);

            for k = 1:channel
                for i = 1:row
                    for j = 1:col
                        if abstractedgrayfinal(i,j,k) <= 0
                            abstractedgrayfinal(i,j,k) = 0;
                        end
                        highlightedimg(i, j, k) = abstractedimg(i,j,k) + abstractedgrayfinal(i,j,k);
                    end
                end
            end

            imshow(highlightedimg, 'Parent', app.UIAxes);
        end

        % Value changed function: RedLevelSlider
        function RedLevelSliderValueChanged(app, event)
            global newimg;
            global tintimg;

            redslider = app.RedLevelSlider.Value/100;
            greenslider = app.GreenLevelSlider.Value/100;
            blueslider = app.BlueLevelSlider.Value/100;

            red = newimg(:, :, 1);
            green = newimg(:, :, 2);
            blue = newimg(:, :, 3);
            
            blackmask = zeros(size(newimg));
            RED = cat(3, red, blackmask, blackmask);
            GREEN = cat(3, blackmask, green, blackmask);
            BLUE = cat(3, blackmask, blackmask, blue);
            
            tintimg = redslider*RED + greenslider*GREEN + blueslider*BLUE;

            imshow(tintimg, 'Parent', app.UIAxes);
        end

        % Button pushed function: TintTickButton
        function TintTickButtonPushed(app, event)
            global tintimg;
            global newimg;

            newimg = tintimg;
            imshow(newimg, 'Parent', app.UIAxes);

            colorSegmentationMenu(app);
        end

        % Button pushed function: TintButton
        function TintButtonPushed(app, event)
            app.TintPanel.Visible = 'on';
        end

        % Button pushed function: LinearBlurButton
        function LinearBlurButtonPushed(app, event)
            global newimg;
            global filterimg;

            doublenewimg = im2double(newimg);
            sizex = size(doublenewimg, 2);
            sizey = size(doublenewimg, 1);
            xcenter = size(doublenewimg,2)/2;
            ycenter = size(doublenewimg,1)/2;
            [X,Y] = meshgrid((1:sizex)-xcenter,(1:sizey)-ycenter);

            R2 = X + Y;
            R2 = rescale(R2);
            weights = (1-R2);

            imgblurred = imgaussfilt(doublenewimg,5);
            weights = rescale(weights);
            filterimg = doublenewimg.*weights + imgblurred.*(1-weights);
            
            imshow(filterimg, 'Parent', app.UIAxes);
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.HTML = uihtml(app.UIFigure);
            app.HTML.HTMLSource = 'index.html';
            app.Panel = uipanel(app.UIFigure);
            app.Panel.Visible = 'off';
            app.UIAxes = uiaxes(app.Panel);
            app.OpenButton_2 = uibutton(app.UIFigure, 'push');
            app.OpenButton_2.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton_2.Icon = fullfile(pathToMLAPP, 'open-folder (6).png');
            app.OpenButton_2.Visible = 'off';
            app.OpenButton_2.Text = 'Open';
            app.SaveButton = uibutton(app.UIFigure, 'push');
            app.SaveButton.ButtonPushedFcn = createCallbackFcn(app, @SaveButtonPushed, true);
            app.SaveButton.Icon = fullfile(pathToMLAPP, 'diskette.png');
            app.SaveButton.Visible = 'off';
            app.SaveButton.Text = 'Save';
            app.ResetButton = uibutton(app.UIFigure, 'push');
            app.ResetButton.ButtonPushedFcn = createCallbackFcn(app, @ResetButtonPushed, true);
            app.ResetButton.Icon = fullfile(pathToMLAPP, 'arrow-circle (2).png');
            app.ResetButton.Visible = 'off';
            app.ResetButton.Text = 'Reset';
            app.ExitButton = uibutton(app.UIFigure, 'push');
            app.ExitButton.ButtonPushedFcn = createCallbackFcn(app, @ExitButtonPushed, true);
            app.ExitButton.Icon = fullfile(pathToMLAPP, 'log-out.png');
            app.ExitButton.Visible = 'off';
            app.ExitButton.Text = 'Exit';
            app.AdjustButton = uibutton(app.UIFigure, 'push');
            app.AdjustButton.ButtonPushedFcn = createCallbackFcn(app, @AdjustButtonPushed, true);
            app.AdjustButton.Icon = fullfile(pathToMLAPP, 'filter.png');
            app.AdjustButton.Visible = 'off';
            app.AdjustButton.Text = '';
            app.AdjustLabel = uilabel(app.UIFigure);
            app.AdjustLabel.Visible = 'off';
            app.AdjustLabel.Text = 'Adjust';
            app.FiltersButton = uibutton(app.UIFigure, 'push');
            app.FiltersButton.ButtonPushedFcn = createCallbackFcn(app, @FiltersButtonPushed, true);
            app.FiltersButton.Icon = fullfile(pathToMLAPP, 'rgb.png');
            app.FiltersButton.Visible = 'off';
            app.FiltersButton.Text = '';
            app.FiltersLabel = uilabel(app.UIFigure);
            app.FiltersLabel.Visible = 'off';
            app.FiltersLabel.Text = 'Filters';
            app.ColorSegmentationButton = uibutton(app.UIFigure, 'push');
            app.ColorSegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @ColorSegmentationButtonPushed, true);
            app.ColorSegmentationButton.Icon = fullfile(pathToMLAPP, 'pen (1).png');
            app.ColorSegmentationButton.Visible = 'off';
            app.ColorSegmentationButton.Text = '';
            app.ColorSegmentationLabel = uilabel(app.UIFigure);
            app.ColorSegmentationLabel.Visible = 'off';
            app.ColorSegmentationLabel.Text = 'Color Segmentation';
            app.AdjustPanel = uipanel(app.UIFigure);
            app.AdjustPanel.Title = 'Adjust ';
            app.AdjustPanel.Visible = 'off';
            app.ResizePanel = uipanel(app.AdjustPanel);
            app.ResizePanel.Title = 'Resize';
            app.ResizetheImageDropDownLabel = uilabel(app.ResizePanel);
            app.ResizetheImageDropDownLabel.Text = 'Resize the Image';
            app.ResizetheImageDropDown = uidropdown(app.ResizePanel);
            app.ResizetheImageDropDown.Items = {'Current', '1920 x 1080', '1280 x 720', '640 x 480', '320 x 240', 'Custom'};
            app.ResizetheImageDropDown.ValueChangedFcn = createCallbackFcn(app, @ResizetheImageDropDownValueChanged, true);
            app.ResizetheImageDropDown.Value = 'Current';
            app.ResizeTickButton = uibutton(app.ResizePanel, 'push');
            app.ResizeTickButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeTickButtonPushed, true);
            app.ResizeTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.ResizeTickButton.Text = '';
            app.ResizeCrossButton = uibutton(app.ResizePanel, 'push');
            app.ResizeCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.ResizeCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.ResizeCrossButton.Text = '';
            app.OrientationButtonGroup = uibuttongroup(app.ResizePanel);
            app.OrientationButtonGroup.Title = 'Orientation';
            app.PortraitButton = uiradiobutton(app.OrientationButtonGroup);
            app.PortraitButton.Text = 'Portrait';
            app.LandscapeButton = uiradiobutton(app.OrientationButtonGroup);
            app.LandscapeButton.Text = 'Landscape';
            app.LandscapeButton.Value = true;
            app.WidthEditFieldLabel = uilabel(app.ResizePanel);
            app.WidthEditFieldLabel.Text = 'Width';
            app.WidthEditField = uieditfield(app.ResizePanel, 'numeric');
            app.HeightEditFieldLabel = uilabel(app.ResizePanel);
            app.HeightEditFieldLabel.Text = 'Height';
            app.HeightEditField = uieditfield(app.ResizePanel, 'numeric');
            app.EnterButton = uibutton(app.ResizePanel, 'push');
            app.EnterButton.ButtonPushedFcn = createCallbackFcn(app, @EnterButtonPushed, true);
            app.EnterButton.Text = 'Enter';
            app.TintPanel = uipanel(app.AdjustPanel);
            app.TintPanel.Title = 'Rotate';
            app.TintTickButton = uibutton(app.TintPanel, 'push');
            app.TintTickButton.ButtonPushedFcn = createCallbackFcn(app, @TintTickButtonPushed, true);
            app.TintTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.TintTickButton.Text = '';
            app.TintCrossButton = uibutton(app.TintPanel, 'push');
            app.TintCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.TintCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.TintCrossButton.Text = '';
            app.RotateLabel_16 = uilabel(app.TintPanel);
            app.RotateLabel_16.Text = 'Red';
            app.RedLevelSlider = uislider(app.TintPanel);
            app.RedLevelSlider.ValueChangedFcn = createCallbackFcn(app, @RedLevelSliderValueChanged, true);
            app.RotateLabel_17 = uilabel(app.TintPanel);
            app.RotateLabel_17.Text = 'Green';
            app.GreenLevelSlider = uislider(app.TintPanel);
            app.RotateLabel_18 = uilabel(app.TintPanel);
            app.RotateLabel_18.Text = 'Blue';
            app.BlueLevelSlider = uislider(app.TintPanel);
            app.BrightnessPanel = uipanel(app.AdjustPanel);
            app.BrightnessPanel.Title = 'Brightness';
            app.BrightnessTickButton = uibutton(app.BrightnessPanel, 'push');
            app.BrightnessTickButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessTickButtonPushed, true);
            app.BrightnessTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.BrightnessTickButton.Text = '';
            app.BrightnessCrossButton = uibutton(app.BrightnessPanel, 'push');
            app.BrightnessCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.BrightnessCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.BrightnessCrossButton.Text = '';
            app.BrightnessLevelSliderLabel = uilabel(app.BrightnessPanel);
            app.BrightnessLevelSliderLabel.Text = 'Brightness Level';
            app.BrightnessLevelSlider = uislider(app.BrightnessPanel);
            app.BrightnessLevelSlider.Limits = [-100 100];
            app.BrightnessLevelSlider.ValueChangedFcn = createCallbackFcn(app, @BrightnessLevelSliderValueChanged, true);
            app.SaturationPanel = uipanel(app.AdjustPanel);
            app.SaturationPanel.Title = 'Saturation';
            app.SaturationTickButton = uibutton(app.SaturationPanel, 'push');
            app.SaturationTickButton.ButtonPushedFcn = createCallbackFcn(app, @SaturationTickButtonPushed, true);
            app.SaturationTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.SaturationTickButton.Text = '';
            app.SaturationCrossButton = uibutton(app.SaturationPanel, 'push');
            app.SaturationCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.SaturationCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.SaturationCrossButton.Text = '';
            app.SaturationLevelSliderLabel = uilabel(app.SaturationPanel);
            app.SaturationLevelSliderLabel.Text = 'Saturation Level';
            app.SaturationLevelSlider = uislider(app.SaturationPanel);
            app.SaturationLevelSlider.Limits = [-100 100];
            app.SaturationLevelSlider.ValueChangedFcn = createCallbackFcn(app, @SaturationLevelSliderValueChanged, true);
            app.ContrastPanel = uipanel(app.AdjustPanel);
            app.ContrastPanel.Title = 'Contrast';
            app.ContrastTickButton = uibutton(app.ContrastPanel, 'push');
            app.ContrastTickButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastTickButtonPushed, true);
            app.ContrastTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.ContrastTickButton.Text = '';
            app.ContrastCrossButton = uibutton(app.ContrastPanel, 'push');
            app.ContrastCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.ContrastCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.ContrastCrossButton.Text = '';
            app.ContrastLevelLabel = uilabel(app.ContrastPanel);
            app.ContrastLevelLabel.Text = 'Contrast Level';
            app.ContrastLevelSlider = uislider(app.ContrastPanel);
            app.ContrastLevelSlider.Limits = [-100 100];
            app.ContrastLevelSlider.ValueChangedFcn = createCallbackFcn(app, @ContrastLevelSliderValueChanged, true);
            app.RotatePanel = uipanel(app.AdjustPanel);
            app.RotatePanel.Title = 'Rotate';
            app.RotateTickButton = uibutton(app.RotatePanel, 'push');
            app.RotateTickButton.ButtonPushedFcn = createCallbackFcn(app, @RotateTickButtonPushed, true);
            app.RotateTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.RotateTickButton.Text = '';
            app.RotateCrossButton = uibutton(app.RotatePanel, 'push');
            app.RotateCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.RotateCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.RotateCrossButton.Text = '';
            app.RotateLabel = uilabel(app.RotatePanel);
            app.RotateLabel.Text = 'Rotate';
            app.RotateLevelSlider = uislider(app.RotatePanel);
            app.RotateLevelSlider.Limits = [0 360];
            app.RotateLevelSlider.ValueChangedFcn = createCallbackFcn(app, @RotateLevelSliderValueChanged, true);
            app.CropPanel = uipanel(app.AdjustPanel);
            app.CropPanel.Title = 'Crop';
            app.CropTickButton = uibutton(app.CropPanel, 'push');
            app.CropTickButton.ButtonPushedFcn = createCallbackFcn(app, @CropTickButtonPushed, true);
            app.CropTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.CropTickButton.Text = '';
            app.CropCrossButton = uibutton(app.CropPanel, 'push');
            app.CropCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeCrossButtonPushed, true);
            app.CropCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.CropCrossButton.Text = '';
            app.DrawtherectangleofyourdesiredAreaLabel = uilabel(app.CropPanel);
            app.DrawtherectangleofyourdesiredAreaLabel.Text = 'Draw the rectangle of your desired Area!';
            app.BackButton = uibutton(app.AdjustPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'left-arrow.png');
            app.BackButton.Text = '';
            app.BrightnessButtonLabel = uilabel(app.AdjustPanel);
            app.BrightnessButtonLabel.Text = 'Brightness';
            app.BrightnessButton = uibutton(app.AdjustPanel, 'push');
            app.BrightnessButton.ButtonPushedFcn = createCallbackFcn(app, @BrightnessButtonPushed, true);
            app.BrightnessButton.Icon = fullfile(pathToMLAPP, 'brightness.png');
            app.BrightnessButton.Text = '';
            app.ResizeButtonLabel = uilabel(app.AdjustPanel);
            app.ResizeButtonLabel.Text = 'Resize';
            app.ResizeButton = uibutton(app.AdjustPanel, 'push');
            app.ResizeButton.ButtonPushedFcn = createCallbackFcn(app, @ResizeButtonPushed, true);
            app.ResizeButton.Icon = fullfile(pathToMLAPP, 'resize-expand.png');
            app.ResizeButton.Text = '';
            app.ContrastButtonLabel = uilabel(app.AdjustPanel);
            app.ContrastButtonLabel.Text = 'Contrast';
            app.ContrastButton = uibutton(app.AdjustPanel, 'push');
            app.ContrastButton.ButtonPushedFcn = createCallbackFcn(app, @ContrastButtonPushed, true);
            app.ContrastButton.Icon = fullfile(pathToMLAPP, 'contrast-circle-symbol.png');
            app.ContrastButton.Text = '';
            app.SaturationButtonLabel = uilabel(app.AdjustPanel);
            app.SaturationButtonLabel.Text = 'Saturation';
            app.SaturationButton = uibutton(app.AdjustPanel, 'push');
            app.SaturationButton.ButtonPushedFcn = createCallbackFcn(app, @SaturationButtonPushed, true);
            app.SaturationButton.Icon = fullfile(pathToMLAPP, 'saturation.png');
            app.SaturationButton.Text = '';
            app.RotateButton = uibutton(app.AdjustPanel, 'push');
            app.RotateButton.ButtonPushedFcn = createCallbackFcn(app, @RotateButtonPushed, true);
            app.RotateButton.Icon = fullfile(pathToMLAPP, 'rotate.png');
            app.RotateButton.Text = '';
            app.RotateButtonLabel = uilabel(app.AdjustPanel);
            app.RotateButtonLabel.Text = 'Rotate';
            app.CropButtonLabel = uilabel(app.AdjustPanel);
            app.CropButtonLabel.Text = 'Crop';
            app.CropButton = uibutton(app.AdjustPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Icon = fullfile(pathToMLAPP, 'crop.png');
            app.CropButton.Text = '';
            app.TintButton = uibutton(app.AdjustPanel, 'push');
            app.TintButton.ButtonPushedFcn = createCallbackFcn(app, @TintButtonPushed, true);
            app.TintButton.Icon = fullfile(pathToMLAPP, 'tint.png');
            app.TintButton.Visible = 'off';
            app.TintButton.Text = '';
            app.TintButtonLabel = uilabel(app.AdjustPanel);
            app.TintButtonLabel.Text = 'Tint';
            app.FilterPanel = uipanel(app.UIFigure);
            app.FilterPanel.Title = 'Filter';
            app.FilterPanel.Visible = 'off';
            app.BackButton_2 = uibutton(app.FilterPanel, 'push');
            app.BackButton_2.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_2.Icon = fullfile(pathToMLAPP, 'left-arrow.png');
            app.BackButton_2.Text = '';
            app.FilterTickButton = uibutton(app.FilterPanel, 'push');
            app.FilterTickButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTickButtonPushed, true);
            app.FilterTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.FilterTickButton.Text = '';
            app.FilterCrossButton = uibutton(app.FilterPanel, 'push');
            app.FilterCrossButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCrossButtonPushed, true);
            app.FilterCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.FilterCrossButton.Text = '';
            app.GrayScaleButton = uibutton(app.FilterPanel, 'push');
            app.GrayScaleButton.ButtonPushedFcn = createCallbackFcn(app, @GrayScaleButtonPushed, true);
            app.GrayScaleButton.Text = 'Gray Scale';
            app.HSVConversionButton = uibutton(app.FilterPanel, 'push');
            app.HSVConversionButton.ButtonPushedFcn = createCallbackFcn(app, @HSVConversionButtonPushed, true);
            app.HSVConversionButton.Text = 'HSV Conversion';
            app.YCbCrConversionButton = uibutton(app.FilterPanel, 'push');
            app.YCbCrConversionButton.ButtonPushedFcn = createCallbackFcn(app, @YCbCrConversionButtonPushed, true);
            app.YCbCrConversionButton.Text = 'YCbCr Conversion';
            app.CannyEdgeDetectionButton = uibutton(app.FilterPanel, 'push');
            app.CannyEdgeDetectionButton.ButtonPushedFcn = createCallbackFcn(app, @CannyEdgeDetectionButtonPushed, true);
            app.CannyEdgeDetectionButton.Text = 'Canny Edge Detection';
            app.VignetteButton = uibutton(app.FilterPanel, 'push');
            app.VignetteButton.ButtonPushedFcn = createCallbackFcn(app, @VignetteButtonPushed, true);
            app.VignetteButton.Text = 'Vignette';
            app.RadialBlurButton = uibutton(app.FilterPanel, 'push');
            app.RadialBlurButton.ButtonPushedFcn = createCallbackFcn(app, @RadialBlurButtonPushed, true);
            app.RadialBlurButton.Text = 'Radial Blur';
            app.LinearBlurButton = uibutton(app.FilterPanel, 'push');
            app.LinearBlurButton.ButtonPushedFcn = createCallbackFcn(app, @LinearBlurButtonPushed, true);
            app.LinearBlurButton.Text = 'Linear Blur';
            app.ImageEnhancementButton = uibutton(app.FilterPanel, 'push');
            app.ImageEnhancementButton.ButtonPushedFcn = createCallbackFcn(app, @ImageEnhancementButtonPushed, true);
            app.ImageEnhancementButton.Text = 'Image Enhancement';
            app.ReduceColorDistortionButton = uibutton(app.FilterPanel, 'push');
            app.ReduceColorDistortionButton.ButtonPushedFcn = createCallbackFcn(app, @ReduceColorDistortionButtonPushed, true);
            app.ReduceColorDistortionButton.Text = 'Reduce Color Distortion';
            app.CannyPanel = uipanel(app.FilterPanel);
            app.CannyPanel.Title = 'Canny Edge Detection';
            app.CannyTickButton = uibutton(app.CannyPanel, 'push');
            app.CannyTickButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTickButtonPushed, true);
            app.CannyTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.CannyTickButton.Text = '';
            app.CannyCrossButton = uibutton(app.CannyPanel, 'push');
            app.CannyCrossButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCrossButtonPushed, true);
            app.CannyCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.CannyCrossButton.Text = '';
            app.RotateLabel_2 = uilabel(app.CannyPanel);
            app.RotateLabel_2.Text = 'Threshold';
            app.CannyLevelSlider = uislider(app.CannyPanel);
            app.CannyLevelSlider.ValueChangedFcn = createCallbackFcn(app, @CannyLevelSliderValueChanged, true);
            app.RadialBlurPanel = uipanel(app.FilterPanel);
            app.RadialBlurPanel.Title = 'Radial Blur';
            app.RadialBlurTickButton = uibutton(app.RadialBlurPanel, 'push');
            app.RadialBlurTickButton.ButtonPushedFcn = createCallbackFcn(app, @FilterTickButtonPushed, true);
            app.RadialBlurTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.RadialBlurTickButton.Text = '';
            app.RadialBlurCrossButton = uibutton(app.RadialBlurPanel, 'push');
            app.RadialBlurCrossButton.ButtonPushedFcn = createCallbackFcn(app, @FilterCrossButtonPushed, true);
            app.RadialBlurCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.RadialBlurCrossButton.Text = '';
            app.RotateLabel_3 = uilabel(app.RadialBlurPanel);
            app.RotateLabel_3.Text = 'Strength';
            app.RadialBlurLevelSlider = uislider(app.RadialBlurPanel);
            app.RadialBlurLevelSlider.ValueChangedFcn = createCallbackFcn(app, @RadialBlurLevelSliderValueChanged, true);
            app.ColorSegmentationPanel = uipanel(app.UIFigure);
            app.ColorSegmentationPanel.Title = 'Color Segmentation';
            app.ColorSegmentationPanel.Visible = 'off';
            app.BackButton_3 = uibutton(app.ColorSegmentationPanel, 'push');
            app.BackButton_3.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton_3.Icon = fullfile(pathToMLAPP, 'left-arrow.png');
            app.BackButton_3.Text = '';
            app.ColorAbstractionButtonLabel = uilabel(app.ColorSegmentationPanel);
            app.ColorAbstractionButtonLabel.Text = 'Color Abstraction';
            app.ColorAbstractionButton = uibutton(app.ColorSegmentationPanel, 'push');
            app.ColorAbstractionButton.ButtonPushedFcn = createCallbackFcn(app, @ColorAbstractionButtonPushed, true);
            app.ColorAbstractionButton.Icon = fullfile(pathToMLAPP, 'detail-page.png');
            app.ColorAbstractionButton.Text = '';
            app.ColorHighlightLabel = uilabel(app.ColorSegmentationPanel);
            app.ColorHighlightLabel.Text = 'Color Highlight';
            app.ColorHighlightButton = uibutton(app.ColorSegmentationPanel, 'push');
            app.ColorHighlightButton.ButtonPushedFcn = createCallbackFcn(app, @ColorHighlightButtonPushed, true);
            app.ColorHighlightButton.Icon = fullfile(pathToMLAPP, 'marker.png');
            app.ColorHighlightButton.Text = '';
            app.ColorAbstractionPanel = uipanel(app.ColorSegmentationPanel);
            app.ColorAbstractionPanel.Title = 'Color Abstraction';
            app.ColorAbstractionTickButton = uibutton(app.ColorAbstractionPanel, 'push');
            app.ColorAbstractionTickButton.ButtonPushedFcn = createCallbackFcn(app, @ColorAbstractionTickButtonPushed, true);
            app.ColorAbstractionTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.ColorAbstractionTickButton.Text = '';
            app.ColorAbstractionCrossButton = uibutton(app.ColorAbstractionPanel, 'push');
            app.ColorAbstractionCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ColorAbstractionCrossButtonPushed, true);
            app.ColorAbstractionCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.ColorAbstractionCrossButton.Text = '';
            app.RotateLabel_4 = uilabel(app.ColorAbstractionPanel);
            app.RotateLabel_4.Text = 'Hue';
            app.UpperHueLevelSlider = uislider(app.ColorAbstractionPanel);
            app.UpperHueLevelSlider.Limits = [0 180];
            app.RotateLabel_5 = uilabel(app.ColorAbstractionPanel);
            app.RotateLabel_5.Text = 'Saturation';
            app.UpperSatLevelSlider = uislider(app.ColorAbstractionPanel);
            app.UpperSatLevelSlider.Limits = [0 255];
            app.RotateLabel_6 = uilabel(app.ColorAbstractionPanel);
            app.RotateLabel_6.Text = 'Value';
            app.UpperValLevelSlider = uislider(app.ColorAbstractionPanel);
            app.UpperValLevelSlider.Limits = [0 255];
            app.RotateLabel_7 = uilabel(app.ColorAbstractionPanel);
            app.RotateLabel_7.Text = 'Hue';
            app.LowerHueLevelSlider = uislider(app.ColorAbstractionPanel);
            app.LowerHueLevelSlider.Limits = [0 180];
            app.RotateLabel_8 = uilabel(app.ColorAbstractionPanel);
            app.RotateLabel_8.Text = 'Saturation';
            app.LowerSatLevelSlider = uislider(app.ColorAbstractionPanel);
            app.LowerSatLevelSlider.Limits = [0 255];
            app.RotateLabel_9 = uilabel(app.ColorAbstractionPanel);
            app.RotateLabel_9.Text = 'Value';
            app.LowerValLevelSlider = uislider(app.ColorAbstractionPanel);
            app.LowerValLevelSlider.Limits = [0 255];
            app.UpperLabel = uilabel(app.ColorAbstractionPanel);
            app.UpperLabel.Text = 'Upper';
            app.LowerLabel = uilabel(app.ColorAbstractionPanel);
            app.LowerLabel.Text = 'Lower';
            app.SelectButton = uibutton(app.ColorAbstractionPanel, 'push');
            app.SelectButton.ButtonPushedFcn = createCallbackFcn(app, @SelectButtonPushed, true);
            app.SelectButton.Text = 'Select';
            app.ColorHighlightPanel = uipanel(app.ColorSegmentationPanel);
            app.ColorHighlightPanel.Title = 'Color Highlight';
            app.ColorHighlightTickButton = uibutton(app.ColorHighlightPanel, 'push');
            app.ColorHighlightTickButton.ButtonPushedFcn = createCallbackFcn(app, @ColorHighlightTickButtonPushed, true);
            app.ColorHighlightTickButton.Icon = fullfile(pathToMLAPP, 'tick.png');
            app.ColorHighlightTickButton.Text = '';
            app.ColorHighlightCrossButton = uibutton(app.ColorHighlightPanel, 'push');
            app.ColorHighlightCrossButton.ButtonPushedFcn = createCallbackFcn(app, @ColorAbstractionCrossButtonPushed, true);
            app.ColorHighlightCrossButton.Icon = fullfile(pathToMLAPP, 'close.png');
            app.ColorHighlightCrossButton.Text = '';
            app.RotateLabel_10 = uilabel(app.ColorHighlightPanel);
            app.RotateLabel_10.Text = 'Hue';
            app.UpperHueLevelSlider_2 = uislider(app.ColorHighlightPanel);
            app.UpperHueLevelSlider_2.Limits = [0 180];
            app.RotateLabel_11 = uilabel(app.ColorHighlightPanel);
            app.RotateLabel_11.Text = 'Saturation';
            app.UpperSatLevelSlider_2 = uislider(app.ColorHighlightPanel);
            app.UpperSatLevelSlider_2.Limits = [0 255];
            app.RotateLabel_12 = uilabel(app.ColorHighlightPanel);
            app.RotateLabel_12.Text = 'Value';
            app.UpperValLevelSlider_2 = uislider(app.ColorHighlightPanel);
            app.UpperValLevelSlider_2.Limits = [0 255];
            app.RotateLabel_13 = uilabel(app.ColorHighlightPanel);
            app.RotateLabel_13.Text = 'Hue';
            app.LowerHueLevelSlider_2 = uislider(app.ColorHighlightPanel);
            app.LowerHueLevelSlider_2.Limits = [0 180];
            app.RotateLabel_14 = uilabel(app.ColorHighlightPanel);
            app.RotateLabel_14.Text = 'Saturation';
            app.LowerSatLevelSlider_2 = uislider(app.ColorHighlightPanel);
            app.LowerSatLevelSlider_2.Limits = [0 255];
            app.RotateLabel_15 = uilabel(app.ColorHighlightPanel);
            app.RotateLabel_15.Text = 'Value';
            app.LowerValLevelSlider_2 = uislider(app.ColorHighlightPanel);
            app.LowerValLevelSlider_2.Limits = [0 255];
            app.UpperLabel_2 = uilabel(app.ColorHighlightPanel);
            app.UpperLabel_2.Text = 'Upper';
            app.LowerLabel_2 = uilabel(app.ColorHighlightPanel);
            app.LowerLabel_2.Text = 'Lower';
            app.SelectButton_2 = uibutton(app.ColorHighlightPanel, 'push');
            app.SelectButton_2.ButtonPushedFcn = createCallbackFcn(app, @SelectButton_2Pushed, true);
            app.SelectButton_2.Text = 'Select';
            app.OpenButton = uibutton(app.UIFigure, 'push');
            app.OpenButton.ButtonPushedFcn = createCallbackFcn(app, @OpenButtonPushed, true);
            app.OpenButton.Icon = fullfile(pathToMLAPP, 'open-folder (2).png');
            app.OpenButton.Text = 'Open';
            app.ByLabel = uilabel(app.UIFigure);
            app.ByLabel.Text = 'By TRUSHTI SELARKA 20BEC131';
            app.AppIcon = uiimage(app.UIFigure);
            app.AppIcon.ImageSource = fullfile(pathToMLAPP, 'image-editing.png');
            app.AppName = uilabel(app.UIFigure);
            app.AppName.Text = 'IMAGE EDITOR';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = Image_Editor

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end